classdef EmocromoAnalyzer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        StoricoSQLitePanel        matlab.ui.container.Panel
        UITable                   matlab.ui.control.Table
        DatiPazienteedEsamePanel  matlab.ui.container.Panel
        EtEditField               matlab.ui.control.NumericEditField
        EtEditFieldLabel          matlab.ui.control.Label
        AnalizzaeSalvaButton      matlab.ui.control.Button
        PLTEditField              matlab.ui.control.NumericEditField
        PLTEditFieldLabel         matlab.ui.control.Label
        WBCEditField              matlab.ui.control.NumericEditField
        WBCEditFieldLabel         matlab.ui.control.Label
        RBCEditField              matlab.ui.control.NumericEditField
        RBCEditFieldLabel         matlab.ui.control.Label
        HGBEditField              matlab.ui.control.NumericEditField
        HGBEditFieldLabel         matlab.ui.control.Label
        SessoDropDown             matlab.ui.control.DropDown
        SessoDropDownLabel        matlab.ui.control.Label
        DiagnosieGraficiPanel     matlab.ui.container.Panel
        EsitoLabel                matlab.ui.control.Label
        UIAxes                    matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Connessione al database SQLite
    conn = sqlite('emocromo.db');
    
    % Query SQL con JOIN per unire Paziente, Esame e Referto
    sqlQuery = ['SELECT P.Codice, P.Sesso, E.HGB, E.RBC, E.WBC, E.PLT, R.Esito_Diagnostico ' ...
                'FROM PAZIENTE P ' ...
                'JOIN ESAME_EMOCROMO E ON P.ID_Paziente = E.ID_Paziente ' ...
                'JOIN REFERTO_ANALISI R ON E.ID_Esame = R.ID_Esame ' ...
                'LIMIT 100;'];
            
    % Estrazione dati sotto forma di Table
    dati = fetch(conn, sqlQuery);
    
    % Chiusura connessione
    close(conn);
    
    % Assegnazione dati alla UITable dell'interfaccia
    if isprop(app, 'UITable')
        app.UITable.Data = dati;
        % Assegnazione dati e nomi delle colonne
    if isprop(app, 'UITable')
        app.UITable.Data = dati;
        app.UITable.ColumnName = {'Codice', 'Sesso', 'HGB', 'RBC', 'WBC', 'PLT', 'Diagnosi'};
    end
    end
        end

        % Button pushed function: AnalizzaeSalvaButton
        function AnalizzaeSalvaButtonPushed(app, event)
            % 1. Lettura dei dati inseriti nell'interfaccia
        sesso = app.SessoDropDown.Value;
        hgb = app.HGBEditField.Value;
        rbc = app.RBCEditField.Value;
        wbc = app.WBCEditField.Value;
        plt = app.PLTEditField.Value;
        
        % Valori calcolati/di default per completare l'esame
        hct = rbc * 3.1;
        mcv = 88.0; 
        mch = 29.0;
        mchc = 33.0;
        
        % 2. Calcolo automatico della diagnosi
        esito = "Valori nella Norma";
        anomaliaPerc = 0.0;
        
        if (sesso == "F" && hgb < 12.0) || (sesso == "M" && hgb < 13.5)
            esito = "Anemia";
            anomaliaPerc = 25.0;
        elseif wbc > 11000 || wbc < 4000
            esito = "Anomalia Globuli Bianchi";
            anomaliaPerc = 30.0;
        elseif plt < 150000
            esito = "Piastrinopenia";
            anomaliaPerc = 20.0;
        end

        % Aggiorna il testo della Label con l'esito calcolato
        app.EsitoLabel.Text = esito;
        if esito == "Valori nella Norma"
          app.EsitoLabel.FontColor = [0 0.6 0]; % Verde
        else
          app.EsitoLabel.FontColor = [0.8 0 0]; % Rosso
        end
        
        % 3. Connessione al database e inserimento coordinato
        conn = sqlite('emocromo.db');
        
        % Trova il prossimo ID Paziente sicuro usando MAX
        maxID = fetch(conn, 'SELECT COALESCE(MAX(ID_Paziente), 0) FROM PAZIENTE;');
        
        if istable(maxID)
            nuovoID = table2array(maxID) + 1;
        elseif iscell(maxID)
            nuovoID = maxID{1,1} + 1;
        else
            nuovoID = maxID + 1;
        end
        
        codicePaz = sprintf('PAZ_%03d', nuovoID);
        
        % Inserimento nelle 3 tabelle garantendo l'allineamento di ID_Paziente e ID_Esame
        sqlPaz = sprintf("INSERT INTO PAZIENTE (ID_Paziente, Codice, Data_Nascita, Sesso) VALUES (%d, '%s', '1990-01-01', '%s');", nuovoID, codicePaz, sesso);
        exec(conn, sqlPaz);
        
        % NOTA: Forziamo ID_Esame = nuovoID per sincronizzarlo con REFERTO_ANALISI
        sqlEsame = sprintf("INSERT INTO ESAME_EMOCROMO (ID_Esame, ID_Paziente, RBC, HGB, HCT, WBC, PLT, MCV, MCH, MCHC) VALUES (%d, %d, %.2f, %.2f, %.2f, %.0f, %.0f, %.2f, %.2f, %.2f);", ...
            nuovoID, nuovoID, rbc, hgb, hct, wbc, plt, mcv, mch, mchc);
        exec(conn, sqlEsame);
        
        sqlRef = sprintf("INSERT INTO REFERTO_ANALISI (ID_Esame, Esito_Diagnostico, Indice_Anomalia_Perc) VALUES (%d, '%s', %.1f);", ...
            nuovoID, esito, anomaliaPerc);
        exec(conn, sqlRef);
        
        % 4. Aggiorna la UITable gestendo i valori NULL con COALESCE
        sqlQuery = ['SELECT P.Codice, P.Sesso, ' ...
                    'COALESCE(E.HGB, 0) AS HGB, ' ...
                    'COALESCE(E.RBC, 0) AS RBC, ' ...
                    'COALESCE(E.WBC, 0) AS WBC, ' ...
                    'COALESCE(E.PLT, 0) AS PLT, ' ...
                    'COALESCE(R.Esito_Diagnostico, ''In attesa'') AS Diagnosi ' ...
                    'FROM PAZIENTE P ' ...
                    'LEFT JOIN ESAME_EMOCROMO E ON P.ID_Paziente = E.ID_Paziente ' ...
                    'LEFT JOIN REFERTO_ANALISI R ON E.ID_Esame = R.ID_Esame ' ...
                    'ORDER BY P.ID_Paziente DESC ' ...
                    'LIMIT 100;'];
        
        datiAggiornati = fetch(conn, sqlQuery);
        close(conn);
        
        app.UITable.Data = datiAggiornati;
        app.UITable.ColumnName = {'Codice', 'Sesso', 'HGB', 'RBC', 'WBC', 'PLT', 'Diagnosi'};
        
        % Messaggio di conferma a schermo
        uialert(app.UIFigure, sprintf('Paziente %s salvato con successo!\nDiagnosi: %s', codicePaz, esito), 'Operazione Completata');

        % 5. Generazione del Grafico nei Range di Riferimento
        % Calcoliamo la % rispetto al valore medio di riferimento:
        % HGB norma ~ 14 g/dL | RBC norma ~ 4.5 M/µL | WBC norma ~ 7500 /µL | PLT norma ~ 250000 /µL
        hgb_pct = (hgb / 14.0) * 100;
        rbc_pct = (rbc / 4.5) * 100;
        wbc_pct = (wbc / 7500.0) * 100;
        plt_pct = (plt / 250000.0) * 100;

        valoriPct = [hgb_pct, rbc_pct, wbc_pct, plt_pct];
        etichette = {'HGB', 'RBC', 'WBC', 'PLT'};

        % Disegna il grafico a barre sull'asse dell'App Designer
        bar(app.UIAxes, valoriPct, 'FaceColor', [0.2 0.4 0.8]);
        
        % Personalizzazione grafica degli assi
        app.UIAxes.XTick = 1:4;
        app.UIAxes.XTickLabel = etichette;
        ylabel(app.UIAxes, 'Scostamento dalla Norma (%)');
        title(app.UIAxes, 'Analisi Quantitativa Parametri');
        grid(app.UIAxes, 'on');
        
        % Traccia la linea del 100% (Valore Medio di Riferimento)
        yline(app.UIAxes, 100, '--r', 'Norma (100%)', 'LineWidth', 1.5);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.DiagnosieGraficiPanel = uipanel(app.UIFigure);
            app.DiagnosieGraficiPanel.Title = 'Diagnosi e Grafici';
            app.UIAxes = uiaxes(app.DiagnosieGraficiPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.EsitoLabel = uilabel(app.DiagnosieGraficiPanel);
            app.EsitoLabel.Text = 'Esito';
            app.DatiPazienteedEsamePanel = uipanel(app.UIFigure);
            app.DatiPazienteedEsamePanel.Title = 'Dati Paziente ed Esame';
            app.SessoDropDownLabel = uilabel(app.DatiPazienteedEsamePanel);
            app.SessoDropDownLabel.Text = 'Sesso';
            app.SessoDropDown = uidropdown(app.DatiPazienteedEsamePanel);
            app.SessoDropDown.Items = {'M', 'F'};
            app.SessoDropDown.Value = 'M';
            app.HGBEditFieldLabel = uilabel(app.DatiPazienteedEsamePanel);
            app.HGBEditFieldLabel.Text = 'HGB';
            app.HGBEditField = uieditfield(app.DatiPazienteedEsamePanel, 'numeric');
            app.RBCEditFieldLabel = uilabel(app.DatiPazienteedEsamePanel);
            app.RBCEditFieldLabel.Text = 'RBC';
            app.RBCEditField = uieditfield(app.DatiPazienteedEsamePanel, 'numeric');
            app.WBCEditFieldLabel = uilabel(app.DatiPazienteedEsamePanel);
            app.WBCEditFieldLabel.Text = 'WBC';
            app.WBCEditField = uieditfield(app.DatiPazienteedEsamePanel, 'numeric');
            app.PLTEditFieldLabel = uilabel(app.DatiPazienteedEsamePanel);
            app.PLTEditFieldLabel.Text = 'PLT';
            app.PLTEditField = uieditfield(app.DatiPazienteedEsamePanel, 'numeric');
            app.AnalizzaeSalvaButton = uibutton(app.DatiPazienteedEsamePanel, 'push');
            app.AnalizzaeSalvaButton.ButtonPushedFcn = createCallbackFcn(app, @AnalizzaeSalvaButtonPushed, true);
            app.AnalizzaeSalvaButton.Text = 'Analizza e Salva';
            app.EtEditFieldLabel = uilabel(app.DatiPazienteedEsamePanel);
            app.EtEditFieldLabel.Text = 'Età';
            app.EtEditField = uieditfield(app.DatiPazienteedEsamePanel, 'numeric');
            app.StoricoSQLitePanel = uipanel(app.UIFigure);
            app.StoricoSQLitePanel.Title = 'Storico SQLite';
            app.UITable = uitable(app.StoricoSQLitePanel);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EmocromoAnalyzer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end